classdef DialogBox_filterParam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        interpolationThresholdEditField  matlab.ui.control.NumericEditField
        interpolationThresholdEditFieldLabel  matlab.ui.control.Label
        ClusterbasedpermutationtestingLabel  matlab.ui.control.Label
        DetectionofoutliersbyHampelmethodLabel  matlab.ui.control.Label
        pThresholdClusterEditField     matlab.ui.control.NumericEditField
        pThresholdClusterEditFieldLabel  matlab.ui.control.Label
        trialThresholdEditField        matlab.ui.control.NumericEditField
        trialThresholdEditFieldLabel   matlab.ui.control.Label
        nPermutationEditField          matlab.ui.control.NumericEditField
        nPermutationEditFieldLabel     matlab.ui.control.Label
        adjacentSampleInterpEditField  matlab.ui.control.NumericEditField
        adjacentSampleInterpEditField_2Label  matlab.ui.control.Label
        nTimesHampel2EditField         matlab.ui.control.NumericEditField
        nTimesHampel2EditField_2Label  matlab.ui.control.Label
        nSigma2EditField               matlab.ui.control.NumericEditField
        nSigma2EditField_2Label        matlab.ui.control.Label
        nTimesHampel1EditField         matlab.ui.control.NumericEditField
        nTimesHampel1EditField_2Label  matlab.ui.control.Label
        nSigma1EditField               matlab.ui.control.NumericEditField
        nSigma1EditField_2Label        matlab.ui.control.Label
        adjacentSampleHamp12EditField  matlab.ui.control.NumericEditField
        adjacentSampleHamp12EditField_2Label  matlab.ui.control.Label
        FrankLabel                     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        OKButton                       matlab.ui.control.Button
        FilteringparametersLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingAppObject)
            
            % We store the calling app with our current app
            app.CallingApp = callingAppObject ;
            
            %We initalize the values of our parameters from what's already
            %written in calling app
            app.adjacentSampleHamp12EditField.Value=app.CallingApp.hampelParameters.adjacentSampleHamp1;
            
            app.nSigma1EditField.Value=app.CallingApp.hampelParameters.nSigma1;
            app.nTimesHampel1EditField.Value=app.CallingApp.hampelParameters.nTimesHampel1;
            
            app.nSigma2EditField.Value=app.CallingApp.hampelParameters.nSigma2;
            app.nTimesHampel2EditField.Value=app.CallingApp.hampelParameters.nTimesHampel2;
            
            app.adjacentSampleInterpEditField.Value=app.CallingApp.hampelParameters.adjacentSampleInterp;
            
            app.nPermutationEditField.Value=app.CallingApp.numberOfPermutations;
            app.trialThresholdEditField.Value=app.CallingApp.trialThreshold;
            app.pThresholdClusterEditField.Value=app.CallingApp.pThresholdCluster;
            app.interpolationThresholdEditField.Value=app.CallingApp.interpolationThreshold;


        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

            %Required order for the parameters in the structure : adjacentSampleHamp1,
            %                                                     nSigma1,nTimesHampel1,
            %                                                     adjacentSampleHamp2,
            %                                                     nSigma2,
            %                                                     nTimesHampel2,
            %                                                     adjacentSampleInterp

            hampelParameters.adjacentSampleHamp1=app.adjacentSampleHamp12EditField.Value;
            hampelParameters.nSigma1=app.nSigma1EditField.Value;
            hampelParameters.nTimesHampel1=app.nTimesHampel1EditField.Value;
            hampelParameters.adjacentSampleHamp2=hampelParameters.adjacentSampleHamp1;
            hampelParameters.nSigma2=app.nSigma2EditField.Value;
            hampelParameters.nTimesHampel2=app.nTimesHampel2EditField.Value;
            hampelParameters.adjacentSampleInterp=app.adjacentSampleInterpEditField.Value;
            
            nPermutations=app.nPermutationEditField.Value; 
            trialThreshold=app.trialThresholdEditField.Value;
            pThresholdCluster=app.pThresholdClusterEditField.Value;
            interpolationThreshold=app.interpolationThresholdEditField.Value;

            dataTransferFromAuxToMainApp(app.CallingApp,hampelParameters, nPermutations, trialThreshold, pThresholdCluster, interpolationThreshold);
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OptionsButton.Enable="on";
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilteringparametersLabel = uilabel(app.UIFigure);
            app.FilteringparametersLabel.Text = 'Filtering parameters';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'FrankHampel.png';
            app.FrankLabel = uilabel(app.UIFigure);
            app.FrankLabel.Text = 'Frank Rudolf Hampel (1941-2018) was a German statistician and a pioneer in robust statistics.';
            app.adjacentSampleHamp12EditField_2Label = uilabel(app.UIFigure);
            app.adjacentSampleHamp12EditField_2Label.Text = 'adjacentSampleHamp (1 & 2)';
            app.adjacentSampleHamp12EditField = uieditfield(app.UIFigure, 'numeric');
            app.nSigma1EditField_2Label = uilabel(app.UIFigure);
            app.nSigma1EditField_2Label.Text = 'nSigma 1';
            app.nSigma1EditField = uieditfield(app.UIFigure, 'numeric');
            app.nTimesHampel1EditField_2Label = uilabel(app.UIFigure);
            app.nTimesHampel1EditField_2Label.Text = 'nTimesHampel 1';
            app.nTimesHampel1EditField = uieditfield(app.UIFigure, 'numeric');
            app.nSigma2EditField_2Label = uilabel(app.UIFigure);
            app.nSigma2EditField_2Label.Text = 'nSigma 2';
            app.nSigma2EditField = uieditfield(app.UIFigure, 'numeric');
            app.nTimesHampel2EditField_2Label = uilabel(app.UIFigure);
            app.nTimesHampel2EditField_2Label.Text = 'nTimesHampel 2';
            app.nTimesHampel2EditField = uieditfield(app.UIFigure, 'numeric');
            app.adjacentSampleInterpEditField_2Label = uilabel(app.UIFigure);
            app.adjacentSampleInterpEditField_2Label.Text = 'adjacentSampleInterp';
            app.adjacentSampleInterpEditField = uieditfield(app.UIFigure, 'numeric');
            app.nPermutationEditFieldLabel = uilabel(app.UIFigure);
            app.nPermutationEditFieldLabel.Text = 'nPermutation';
            app.nPermutationEditField = uieditfield(app.UIFigure, 'numeric');
            app.trialThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.trialThresholdEditFieldLabel.Text = 'trialThreshold';
            app.trialThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.pThresholdClusterEditFieldLabel = uilabel(app.UIFigure);
            app.pThresholdClusterEditFieldLabel.Text = 'pThresholdCluster';
            app.pThresholdClusterEditField = uieditfield(app.UIFigure, 'numeric');
            app.DetectionofoutliersbyHampelmethodLabel = uilabel(app.UIFigure);
            app.DetectionofoutliersbyHampelmethodLabel.Text = 'Detection of outliers by Hampel method';
            app.ClusterbasedpermutationtestingLabel = uilabel(app.UIFigure);
            app.ClusterbasedpermutationtestingLabel.Text = 'Cluster based permutation testing';
            app.interpolationThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.interpolationThresholdEditFieldLabel.Text = 'interpolationThreshold';
            app.interpolationThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBox_filterParam(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end